classdef filterapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputUIAxes                    matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        SpatialFiltersTab              matlab.ui.container.Tab
        SelectkernelsizeDropDownLabel  matlab.ui.control.Label
        SelectkernelsizeDropDown       matlab.ui.control.DropDown
        ApplyFilterButton              matlab.ui.control.Button
        Predefined2DfiltersLabel       matlab.ui.control.Label
        AlphaEditFieldLabel            matlab.ui.control.Label
        AlphaEditField                 matlab.ui.control.NumericEditField
        LaplacianButton                matlab.ui.control.Button
        LenEditFieldLabel              matlab.ui.control.Label
        LenEditField                   matlab.ui.control.NumericEditField
        ThetaEditFieldLabel            matlab.ui.control.Label
        ThetaEditField                 matlab.ui.control.NumericEditField
        MotionButton                   matlab.ui.control.Button
        ApplyPrewittButton             matlab.ui.control.Button
        ApplySobelButton               matlab.ui.control.Button
        PassFiltersTab                 matlab.ui.container.Tab
        GuassianFilterLabel            matlab.ui.control.Label
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        StandardDeviationEditField     matlab.ui.control.NumericEditField
        ApplyHighPassButton            matlab.ui.control.Button
        ApplyLowPassButton             matlab.ui.control.Button
        ButterworthFilterLabel         matlab.ui.control.Label
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        ApplyHighPassButton_2          matlab.ui.control.Button
        ApplyLowPassButton_2           matlab.ui.control.Button
        RejectFiltersTab               matlab.ui.container.Tab
        Radius1SpinnerLabel            matlab.ui.control.Label
        Radius1Spinner                 matlab.ui.control.Spinner
        Radius2SpinnerLabel            matlab.ui.control.Label
        Radius2Spinner                 matlab.ui.control.Spinner
        ApplyBandPassButton            matlab.ui.control.Button
        ApplyBandRejectButton          matlab.ui.control.Button
        OutputUIAxes                   matlab.ui.control.UIAxes
        UploadButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        mainInst % instance of the class calling the app
        inputImg % input image
        defaultPic % default folder for pics
        outputImg % output image
        filterImg % for filtering image files
    end

    methods (Access = public)
    
        function initValues(app)
            app.filterImg = {'*.jpg';'*.jpeg';'*.png';'*.tif' };
            app.defaultPic = './default_pics/';
        end
        
        function output = applyBF(app, img, thresh, order)
            h=size(img,1);
            w=size(img,2);
            fftim = fftshift(fft2(double(img)));
            [x, y] = meshgrid(-floor(w/2):floor(w/2)-1,-floor(h/2):floor(h/2)-1);
            
            B = sqrt(2) - 1; %// Define B
            D = sqrt(x.^2 + y.^2); %// Define distance to centre
            hhp = 1 ./ (1 + B * ((thresh ./ D).^(2 * order)));
            out_spec_centre = fftim .* hhp;
            
            %// Uncentre spectrum
            out_spec = ifftshift(out_spec_centre);
            
            %// Inverse FFT, get real components
            out = real(ifft2(out_spec));
            
            %// Normalize and cast
            out = (out - min(out(:))) / (max(out(:)) - min(out(:)));
            output = uint8(255*out);
        end
        
        function output = applyBandFilter(app, img, r1, r2, passType)
            [row, col] = size(img);
            mask = zeros(row, col);
            mask(floor(row/2),floor(col/2)) = 1;
            mask = bwdist(mask);
            ftImage = fftshift(fft2(img));
            
            if (passType == "pass")
                mask = mask > r1 & mask < r2;
            elseif (passType == "reject")
                mask = mask < r1 | mask > r2;
            end
            output =  uint8(abs(ifft2(ifftshift(ftImage.*mask))));
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inst, img)
            initValues(app);
            app.mainInst = inst;
            
            title(app.InputUIAxes, []);
            xlabel(app.InputUIAxes, []);
            ylabel(app.InputUIAxes, []);
            app.InputUIAxes.XAxis.TickLabels = {};
            app.InputUIAxes.YAxis.TickLabels = {};
            
            title(app.OutputUIAxes, []);
            xlabel(app.OutputUIAxes, []);
            ylabel(app.OutputUIAxes, []);
            app.OutputUIAxes.XAxis.TickLabels = {};
            app.OutputUIAxes.YAxis.TickLabels = {};          
            
            if isempty(img)
                disp('I am not there');
                f = strcat(app.defaultPic, 'no_img.jpg');
                temp = imread(f);
                app.inputImg = temp;
                imshow(temp, 'Parent', app.InputUIAxes);
            else 
                disp('I am there');
                app.inputImg = img;
                imshow(img, 'Parent', app.InputUIAxes);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            arg = 'success';
            mainCallback(app.mainInst, arg, app.outputImg);
            delete(app)
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile(app.filterImg, ...
                'Select an image');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
                 app.inputImg = imread([path,file]);
                 imshow(app.inputImg, 'Parent', app.InputUIAxes);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile(app.filterImg, 'Filtered File');
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(path,file),...
                    ' and then clicked Save.'])
                filePath = strcat(path,file);
                imwrite(app.outputImg, filePath);
            end
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            h = ones(1,1);
            switch app.SelectkernelsizeDropDown.Value
                case '3x3'
                    h = ones(3,3)/9;
                case '5x5'
                    h = ones(5,5)/25;
                case '7x7'
                    h = ones(7,7)/49;
                case '9x9'
                    h = ones(9,9)/81;
                case '13x13'
                    h = ones(13,13)/169;
                case '49x49'
                    h = ones(49,49)/2401;
                case '81x81'
                    h = ones(81,81)/6561;
                case '121x121'
                    h = ones(121,121)/14641;
                otherwise
                    disp('no window selected');
            end
            imDouble = im2double(rgb2gray(app.inputImg));        
            app.outputImg = im2uint8(imfilter(imDouble, h, ...
                'replicate'));
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            imDouble = im2double(rgb2gray(app.inputImg));
            h = fspecial('laplacian',app.AlphaEditField.Value);
            app.outputImg = im2uint8(imfilter(imDouble, h));
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: MotionButton
        function MotionButtonPushed(app, event)
            imDouble = im2double(rgb2gray(app.inputImg));
            h = fspecial('motion',app.LenEditField.Value, ...
                app.ThetaEditField.Value);
            app.outputImg = im2uint8(imfilter(imDouble,h,'replicate'));
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: ApplyPrewittButton
        function ApplyPrewittButtonPushed(app, event)
            imDouble = im2double(rgb2gray(app.inputImg));
            h = fspecial('prewitt');
            app.outputImg = im2uint8(imfilter(imDouble, h));
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: ApplySobelButton
        function ApplySobelButtonPushed(app, event)
            imDouble = im2double(rgb2gray(app.inputImg));
            h = fspecial('sobel');
            app.outputImg = im2uint8(imfilter(imDouble, h));
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: ApplyLowPassButton
        function ApplyLowPassButtonPushed(app, event)
            imGray = rgb2gray(app.inputImg);
            app.outputImg = imgaussfilt(imGray, ...
                app.StandardDeviationEditField.Value);
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: ApplyHighPassButton
        function ApplyHighPassButtonPushed(app, event)
            imGray = rgb2gray(app.inputImg);
            app.outputImg = imGray - imgaussfilt(imGray, ...
                app.StandardDeviationEditField.Value);
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: ApplyLowPassButton_2
        function ApplyLowPassButton_2Pushed(app, event)
            imGray = rgb2gray(app.inputImg);
            app.outputImg = imGray - applyBF(app, imGray, ...
                app.ThresholdEditField.Value, 2);
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: ApplyHighPassButton_2
        function ApplyHighPassButton_2Pushed(app, event)
            imGray = rgb2gray(app.inputImg);
            app.outputImg = applyBF(app, imGray, ...
                app.ThresholdEditField.Value, 2);
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: ApplyBandPassButton
        function ApplyBandPassButtonPushed(app, event)
            imGray = rgb2gray(app.inputImg);
            app.outputImg = applyBandFilter(app, imGray, app.Radius1Spinner.Value, ...
                app.Radius2Spinner.Value, "pass");
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: ApplyBandRejectButton
        function ApplyBandRejectButtonPushed(app, event)
            imGray = rgb2gray(app.inputImg);
            app.outputImg = applyBandFilter(app, imGray, app.Radius1Spinner.Value, ...
                app.Radius2Spinner.Value, "reject");
            imshow(app.outputImg, 'Parent', app.OutputUIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InputUIAxes = uiaxes(app.UIFigure);
            title(app.InputUIAxes, 'Title')
            xlabel(app.InputUIAxes, 'X')
            ylabel(app.InputUIAxes, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SpatialFiltersTab = uitab(app.TabGroup);
            app.SpatialFiltersTab.Title = 'Spatial Filters';
            app.SelectkernelsizeDropDownLabel = uilabel(app.SpatialFiltersTab);
            app.SelectkernelsizeDropDownLabel.Text = 'Select kernel size';
            app.SelectkernelsizeDropDown = uidropdown(app.SpatialFiltersTab);
            app.SelectkernelsizeDropDown.Items = {'3x3', '5x5', '7x7', '9x9', '13x13', '49x49', '81x81', '121x121'};
            app.SelectkernelsizeDropDown.Value = '3x3';
            app.ApplyFilterButton = uibutton(app.SpatialFiltersTab, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.Predefined2DfiltersLabel = uilabel(app.SpatialFiltersTab);
            app.Predefined2DfiltersLabel.Text = 'Predefined 2-D filters :';
            app.AlphaEditFieldLabel = uilabel(app.SpatialFiltersTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.SpatialFiltersTab, 'numeric');
            app.LaplacianButton = uibutton(app.SpatialFiltersTab, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.LenEditFieldLabel = uilabel(app.SpatialFiltersTab);
            app.LenEditFieldLabel.Text = 'Len';
            app.LenEditField = uieditfield(app.SpatialFiltersTab, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.SpatialFiltersTab);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.SpatialFiltersTab, 'numeric');
            app.MotionButton = uibutton(app.SpatialFiltersTab, 'push');
            app.MotionButton.ButtonPushedFcn = createCallbackFcn(app, @MotionButtonPushed, true);
            app.MotionButton.Text = 'Motion';
            app.ApplyPrewittButton = uibutton(app.SpatialFiltersTab, 'push');
            app.ApplyPrewittButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyPrewittButtonPushed, true);
            app.ApplyPrewittButton.Text = 'Apply Prewitt';
            app.ApplySobelButton = uibutton(app.SpatialFiltersTab, 'push');
            app.ApplySobelButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySobelButtonPushed, true);
            app.ApplySobelButton.Text = 'Apply Sobel';
            app.PassFiltersTab = uitab(app.TabGroup);
            app.PassFiltersTab.Title = 'Pass Filters';
            app.GuassianFilterLabel = uilabel(app.PassFiltersTab);
            app.GuassianFilterLabel.Text = 'Guassian Filter';
            app.StandardDeviationEditFieldLabel = uilabel(app.PassFiltersTab);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviationEditField = uieditfield(app.PassFiltersTab, 'numeric');
            app.ApplyHighPassButton = uibutton(app.PassFiltersTab, 'push');
            app.ApplyHighPassButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHighPassButtonPushed, true);
            app.ApplyHighPassButton.Text = 'Apply High Pass';
            app.ApplyLowPassButton = uibutton(app.PassFiltersTab, 'push');
            app.ApplyLowPassButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLowPassButtonPushed, true);
            app.ApplyLowPassButton.Text = 'Apply Low Pass';
            app.ButterworthFilterLabel = uilabel(app.PassFiltersTab);
            app.ButterworthFilterLabel.Text = 'Butterworth Filter';
            app.ThresholdEditFieldLabel = uilabel(app.PassFiltersTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.PassFiltersTab, 'numeric');
            app.ApplyHighPassButton_2 = uibutton(app.PassFiltersTab, 'push');
            app.ApplyHighPassButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyHighPassButton_2Pushed, true);
            app.ApplyHighPassButton_2.Text = 'Apply High Pass';
            app.ApplyLowPassButton_2 = uibutton(app.PassFiltersTab, 'push');
            app.ApplyLowPassButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyLowPassButton_2Pushed, true);
            app.ApplyLowPassButton_2.Text = 'Apply Low Pass';
            app.RejectFiltersTab = uitab(app.TabGroup);
            app.RejectFiltersTab.Title = 'Reject Filters';
            app.Radius1SpinnerLabel = uilabel(app.RejectFiltersTab);
            app.Radius1SpinnerLabel.Text = 'Radius 1';
            app.Radius1Spinner = uispinner(app.RejectFiltersTab);
            app.Radius2SpinnerLabel = uilabel(app.RejectFiltersTab);
            app.Radius2SpinnerLabel.Text = 'Radius 2';
            app.Radius2Spinner = uispinner(app.RejectFiltersTab);
            app.ApplyBandPassButton = uibutton(app.RejectFiltersTab, 'push');
            app.ApplyBandPassButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyBandPassButtonPushed, true);
            app.ApplyBandPassButton.Text = 'Apply Band Pass';
            app.ApplyBandRejectButton = uibutton(app.RejectFiltersTab, 'push');
            app.ApplyBandRejectButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyBandRejectButtonPushed, true);
            app.ApplyBandRejectButton.Text = 'Apply Band Reject';
            app.OutputUIAxes = uiaxes(app.UIFigure);
            title(app.OutputUIAxes, 'Title')
            xlabel(app.OutputUIAxes, 'X')
            ylabel(app.OutputUIAxes, 'Y')
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = filterapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end