classdef fusionapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes_3          matlab.ui.control.UIAxes
        OpenImage1Button  matlab.ui.control.Button
        OpenImage2Button  matlab.ui.control.Button
        FuseImagesButton  matlab.ui.control.Button
        SaveImageButton   matlab.ui.control.Button
    end


    properties (Access = public)
        filterImg % for filtering image files
        inputImg % input image
        inputImg2 % input image
        outputImg % output image
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filterImg = {'*.jpg';'*.jpeg';'*.png';'*.tif' };
        end

        % Button pushed function: OpenImage1Button
        function OpenImage1ButtonPushed(app, event)
            [file,path] = uigetfile(app.filterImg, ...
                'Select an image');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
                 app.inputImg = imread([path,file]);
                 imshow(app.inputImg, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: OpenImage2Button
        function OpenImage2ButtonPushed(app, event)
            [file,path] = uigetfile(app.filterImg, ...
                'Select an image');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
                 app.inputImg2 = imread([path,file]);
                 imshow(app.inputImg2, 'Parent', app.UIAxes_2);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            if (~isempty(app.outputImg))
                [file, path] = uiputfile(app.filterImg, 'Workspace File');
                if isequal(file,0) || isequal(path,0)
                    disp('User clicked Cancel.')
                else
                    disp(['User selected ',fullfile(path,file),...
                        ' and then clicked Save.'])
                    filePath = strcat(path,file);
                    imwrite(app.outputImg, filePath);
                end
            end
        end

        % Button pushed function: FuseImagesButton
        function FuseImagesButtonPushed(app, event)
            app.inputImg = rgb2gray(app.inputImg);
            app.inputImg2 = rgb2gray(app.inputImg2);
            
            [cA1,cH1,cV1,cD1] = dwt2(app.inputImg,'bior3.7');
            [cA2,cH2,cV2,cD2] = dwt2(app.inputImg2,'bior3.7');
            
            ex = (cA1 + cA2)/2;
            
            Xsyn = idwt2(ex,cH2,cV2,cD2,'bior3.7');
            Xsyn = uint8(Xsyn);
            app.outputImg = Xsyn;
            imshow(Xsyn, 'Parent', app.UIAxes_3);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image1')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Image2')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Fused Images')
            app.OpenImage1Button = uibutton(app.UIFigure, 'push');
            app.OpenImage1Button.ButtonPushedFcn = createCallbackFcn(app, @OpenImage1ButtonPushed, true);
            app.OpenImage1Button.Text = 'Open Image 1';
            app.OpenImage2Button = uibutton(app.UIFigure, 'push');
            app.OpenImage2Button.ButtonPushedFcn = createCallbackFcn(app, @OpenImage2ButtonPushed, true);
            app.OpenImage2Button.Text = 'Open Image 2';
            app.FuseImagesButton = uibutton(app.UIFigure, 'push');
            app.FuseImagesButton.ButtonPushedFcn = createCallbackFcn(app, @FuseImagesButtonPushed, true);
            app.FuseImagesButton.Text = 'Fuse Images';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = fusionapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end