classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UploadImageButton              matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        TransformTab                   matlab.ui.container.Tab
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        ScaleButton                    matlab.ui.control.Button
        xEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.NumericEditField
        xEditField_3Label              matlab.ui.control.Label
        xEditField_3                   matlab.ui.control.NumericEditField
        yEditField_3Label              matlab.ui.control.Label
        yEditField_3                   matlab.ui.control.NumericEditField
        qEditFieldLabel                matlab.ui.control.Label
        qEditField                     matlab.ui.control.NumericEditField
        ShearButton                    matlab.ui.control.Button
        TranslateButton                matlab.ui.control.Button
        RotateindegButton              matlab.ui.control.Button
        ApplyButton_4                  matlab.ui.control.Button
        AffineLabel                    matlab.ui.control.Label
        FourierTransformLabel          matlab.ui.control.Label
        DiscreteCosineTransformLabel   matlab.ui.control.Label
        ApplyButton_5                  matlab.ui.control.Button
        WaveletTab                     matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        ApplyButton_2                  matlab.ui.control.Button
        ApproximationLabel             matlab.ui.control.Label
        HorizontalLabel                matlab.ui.control.Label
        VerticalLabel                  matlab.ui.control.Label
        DiagonalLabel                  matlab.ui.control.Label
        QualityTab                     matlab.ui.container.Tab
        InputMinEditFieldLabel         matlab.ui.control.Label
        InputMinEditField              matlab.ui.control.NumericEditField
        InputMaxEditFieldLabel         matlab.ui.control.Label
        InputMaxEditField              matlab.ui.control.NumericEditField
        ApplyChangesButton             matlab.ui.control.Button
        InputMinEditField_2Label       matlab.ui.control.Label
        InputMinEditField_2            matlab.ui.control.NumericEditField
        InputMaxEditField_2Label       matlab.ui.control.Label
        InputMaxEditField_2            matlab.ui.control.NumericEditField
        BrightnessLabel                matlab.ui.control.Label
        ContrastLabel_2                matlab.ui.control.Label
        AdvancedOptionsTab             matlab.ui.container.Tab
        SelectcolorModelDropDownLabel  matlab.ui.control.Label
        SelectcolorModelDropDown       matlab.ui.control.DropDown
        AdvancedDropDownLabel          matlab.ui.control.Label
        AdvancedDropDown               matlab.ui.control.DropDown
        ApplyButton                    matlab.ui.control.Button
        OpenFilterToolButton           matlab.ui.control.Button
        OpenNoiseToolButton            matlab.ui.control.Button
        OpenFusionToolButton           matlab.ui.control.Button
        InpUIAxes                      matlab.ui.control.UIAxes
        OutUIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        filepath; % filepath of the input image
        filterImg % for filtering image files
        inputImg % input image
        defaultPic % default folder for pics
        outputImg % output image
        
    end
    
    methods (Access = public)
        
        function mainCallback(app, arg, img)
            
            disp('I am in main call back');
            
            if (~ isempty(img))
                app.outputImg = img;
                disp(size(app.outputImg));
                imshow(img, 'Parent', app.OutUIAxes);
            end
        end
        
        function output = applyRGB(app, image)
            
            redLayer = image(:,:,1);
            greenLayer = image(:,:,2);
            blueLayer = image(:,:,3);
            output = cat(3, redLayer,blueLayer,greenLayer);
        end
        
        function output = applyHSI(app, image)
            redLayer = image(:,:,1);
            greenLayer = image(:,:,2);
            blueLayer = image(:,:,3);
            H = getHue(app, redLayer, greenLayer, blueLayer);
            disp(size(H));
            S = getSaturation(app, redLayer, greenLayer, blueLayer);
            disp(size(S));
            I = (redLayer+greenLayer+blueLayer)./3;
            disp(size(I));
            output = cat(3,H,S,I);
        end
        
        function output = applyCMYK(app, image)
            image = im2double(image);
            cform = makecform('srgb2cmyk');
            cmykI = applycform(image,cform);
            disp(size(cmykI));
            cLayer = cmykI(:,:,1);
            mLayer = cmykI(:,:,2);
            yLayer = cmykI(:,:,3);
            output = cat(3,cLayer,mLayer,yLayer);
            
        end
        
        function output = applyCMY(app, image)
            image = im2double(image);
            redLayer = image(:,:,1);
            greenLayer = image(:,:,2);
            blueLayer = image(:,:,3);
            
            C = 1 - redLayer;
            M = 1 - greenLayer;
            Y = 1 - blueLayer;
            
            colorCMY = cat(3,C,M,Y);
            output = colorCMY;
        end
        
        function output = getSaturation(app, r,g,b)
            [row, col] = size(r);
            sComponent = zeros(row,col);
            for i=1:row
                for j=1:col
                    minVal1 = min(r(i,j),g(i,j));
                    minVal2 = min(g(i,j),b(i,j));
                    minVal3 = min(minVal1,minVal2);
                    minVal = min(minVal3, min( r(i,j),b(i,j) ));
                    sComponent(i,j) = 1 - ( (3./(r(i,j)+g(i,j)+b(i,j))).*(minVal) );
                end
            end
            output = sComponent;
        end
        
        function output = getHue(app, ruint8,guint8,buint8)
            r = double(ruint8);
            g = double(guint8);
            b = double(buint8);
            [row, col] = size(r);
            hComponent = zeros(row, col);
            num = ( (r-g) + (r-b) )/2;
            den = ( (r-g).^2 + (r-b).*(g-b) ).^2;
            hComponent = acos(num./den);
            temp =  b>g;
            for i=1:row
                for j=1:col
                    if temp(i,j) == 1
                        hComponent(i,j) = 360 - hComponent(i,j);
                    end
                end
            end
            output = hComponent;
        end
        
        %TRANSFORMS
        
        function output = applyScale(app, image, x, y)
            scale_mat = [x 0 0; 0 y 0; 0 0 1];
            ref = imref2d(size(image));
            %scale image
            tform = affine2d(scale_mat);
            scaled = imwarp(image,tform);
            output = scaled;
        end
        
        function output = applyTranslate(app, image, x, y)
            trans_mat = [1 0 0; 0 1 0; x y 1];
            ref = imref2d(size(image));
            %translate image
            tform = affine2d(trans_mat);
            trans = imwarp(image,tform,'outputview',ref);
            output = trans;
        end
        
        function output = applyShear(app, image, x, y)
            shear_mat = [1 y 0; x 1 0; 0 0 1];
            ref = imref2d(size(image));
            %shear image
            tform = affine2d(shear_mat);
            shear = imwarp(image,tform);
            output = shear;
        end
        
        function output = applyRotate(app, image, q)
            rot_mat = [cos(q) sin(q) 0; -sin(q) cos(q) 0; 0 0 1];
            ref = imref2d(size(image));
            %rotate image
            tform = affine2d(rot_mat);
            rot = imwarp(image,tform);
            output = rot;
        end
        
        function output = FTransform(app, image)
            image = rgb2gray(image);
            image = fft2(image);
            F = fftshift(image);
            F2 = log(abs(F));
            output = F2;
        end
        
        function [cA,cH,cV,cD] = applyDWT2(app, image)
            [cA,cH,cV,cD] = dwt2(image,'db45');
        end
        
        function output = applyDCT2(app, image)
            output = rgb2gray(image);
            %             J = dct2(image);
            %             K = log(abs(J));
            %             output = K;
        end
        
        function output = applyQuality(app, image, w, x, y, z)
            im_bright = imadjust(image,[0,w],[x,1]);
            im_contrast = imadjust(im_bright,[y,1],[0,z]);
            output = histeq(im_contrast);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, s)
            title(app.InpUIAxes, []);
            xlabel(app.InpUIAxes, []);
            ylabel(app.InpUIAxes, []);
            app.InpUIAxes.XAxis.TickLabels = {};
            app.InpUIAxes.YAxis.TickLabels = {};
            
            title(app.OutUIAxes, []);
            xlabel(app.OutUIAxes, []);
            ylabel(app.OutUIAxes, []);
            app.OutUIAxes.XAxis.TickLabels = {};
            app.OutUIAxes.YAxis.TickLabels = {};
            
            app.defaultPic = './default_pics/';
            app.filterImg = {'*.jpg';'*.jpeg';'*.png';'*.tif' };
            
            title(app.UIAxes2, []);
            xlabel(app.UIAxes2, []);
            ylabel(app.UIAxes2, []);
            app.UIAxes2.XAxis.TickLabels = {};
            app.UIAxes2.YAxis.TickLabels = {};
            
            title(app.UIAxes3, []);
            xlabel(app.UIAxes3, []);
            ylabel(app.UIAxes3, []);
            app.UIAxes3.XAxis.TickLabels = {};
            app.UIAxes3.YAxis.TickLabels = {};
            
            title(app.UIAxes4, []);
            xlabel(app.UIAxes4, []);
            ylabel(app.UIAxes4, []);
            app.UIAxes4.XAxis.TickLabels = {};
            app.UIAxes4.YAxis.TickLabels = {};
            
            title(app.UIAxes5, []);
            xlabel(app.UIAxes5, []);
            ylabel(app.UIAxes5, []);
            app.UIAxes5.XAxis.TickLabels = {};
            app.UIAxes5.YAxis.TickLabels = {};
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file,path] = uigetfile(app.filterImg, ...
                'Select an image');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                app.inputImg = imread([path,file]);
                app.filepath = strcat(path,file);
                disp(app.filepath);
                imshow(app.inputImg, 'Parent', app.InpUIAxes);
            end
        end

        % Button pushed function: OpenNoiseToolButton
        function OpenNoiseToolButtonPushed(app, event)
            %if (~ isempty(app.inputImg))
            noise(app,app.inputImg);
            %end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if (~isempty(app.outputImg))
                [file, path] = uiputfile(app.filterImg, 'Workspace File');
                if isequal(file,0) || isequal(path,0)
                    disp('User clicked Cancel.')
                else
                    disp(['User selected ',fullfile(path,file),...
                        ' and then clicked Save.'])
                    filePath = strcat(path,file);
                    imwrite(app.outputImg, filePath);
                end
            end
        end

        % Button pushed function: OpenFilterToolButton
        function OpenFilterToolButtonPushed(app, event)
            %if (~ isempty(app.inputImg))
            filterapp(app,app.inputImg);
            %end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if (strcmp(app.AdvancedDropDown.Value,'Compress Image'))
                x = app.inputImg;
                
                [rows, cols] = size(x);
                DF = zeros(rows, cols);
                DFF = zeros(rows, cols);
                IDF = zeros(rows, cols);
                IDFF = zeros(rows, cols);
                depth = 2;
                blockSize = 8;
                
                for cont1 = 1: blockSize:rows
                    for cont2 = 1: blockSize:cols
                      		f = x(cont1:cont1+ blockSize-1, cont2:cont2+ blockSize-1);
                        df = dct2(f);
                        DF (cont1:cont1+ blockSize-1, cont2:cont2+blockSize-1) = df; %dct of block
                        dff = idct2(df);
                        DFF(cont1:cont1+ blockSize-1, cont2:cont2+blockSize-1) = dff; %idct of block
                        
                        df(blockSize:-1:depth+1, :) = 0;
                        df(:, blockSize:-1:depth+1) = 0;
                        IDF(cont1:cont1+blockSize-1, cont2:cont2+blockSize-1) = df; %combing dct blocks into image
                        dff = idct2(df);
                        IDFF(cont1:cont1+blockSize-1, cont2:cont2+blockSize-1) = dff; %combing dct blocks into image
                    end
                end
                app.outputImg = uint8(IDFF);
                imshow(uint8(IDFF), 'Parent', app.OutUIAxes);
            end
            if (strcmp(app.AdvancedDropDown.Value,'Insert Visible Watermark'))
                x = app.inputImg;
                y = imread('Matlab.jpg');
                im = rgb2gray(x);
                im2 = rgb2gray(y);
                
                
                bit8_2 = bitget(im2, 8)*2^6;
                bit1 = bitget(im, 1)*2^0;
                bit2 = bitget(im, 2)*2^1;
                bit3 = bitget(im, 3)*2^2;
                bit4 = bitget(im, 4)*2^3;
                bit5 = bitget(im, 5)*2^4;
                bit6 = bitget(im, 6)*2^5;
                bit7 = bitget(im, 7)*2^6;
                bit8 = bitget(im, 8)*2^7;
                
                for i = 1:256
                    for j = 1:256
                        if(i < 180 || j < 180)
                            bit8_2(i,j) = bit7(i,j);
                        end
                    end
                end
                
                reconstructed = (bit1 + bit2 + bit3 + bit4 + bit5 + bit6 + bit8_2 + bit8);
                app.outputImg = reconstructed;
                imshow(reconstructed, 'Parent', app.OutUIAxes);
            end
            if (strcmp(app.AdvancedDropDown.Value,'Insert Invisible Watermark'))
                x = app.inputImg;
                y = imread('Matlab.jpg');
                im = rgb2gray(x);
                im2 = rgb2gray(y);
                
                
                bit8_2 = bitget(im2, 2)*2^1;
                bit1 = bitget(im, 1)*2^0;
                bit2 = bitget(im, 2)*2^1;
                bit3 = bitget(im, 3)*2^2;
                bit4 = bitget(im, 4)*2^3;
                bit5 = bitget(im, 5)*2^4;
                bit6 = bitget(im, 6)*2^5;
                bit7 = bitget(im, 7)*2^6;
                bit8 = bitget(im, 8)*2^7;
                
                
                reconstructed = (bit1 + bit8_2 + bit3 + bit4 + bit5 + bit6 + bit7 + bit8);
                app.outputImg = reconstructed;
                imshow(reconstructed, 'Parent', app.OutUIAxes);
            end
            if (strcmp(app.AdvancedDropDown.Value,'Remove S&P Noise'))
                Kaverage = medfilt2(app.inputImg);
                app.outputImg = Kaverage;
                imshow(Kaverage, 'Parent', app.OutUIAxes);
            end
            if (strcmp(app.AdvancedDropDown.Value,'Remove Gaussian Noise'))
                K = wiener2(app.inputImg,[5 5]);
                app.outputImg = K;
                imshow(K, 'Parent', app.OutUIAxes);
            end
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            image = app.inputImg;
            app.outputImg = applyScale(app, image, x, y);
            imshow(app.outputImg, 'Parent', app.OutUIAxes);
        end

        % Button pushed function: ShearButton
        function ShearButtonPushed(app, event)
            x = app.xEditField_2.Value;
            y = app.yEditField_2.Value;
            image = app.inputImg;
            app.outputImg = applyShear(app, image, x, y);
            imshow(app.outputImg, 'Parent', app.OutUIAxes);
        end

        % Button pushed function: TranslateButton
        function TranslateButtonPushed(app, event)
            x = app.xEditField_3.Value;
            y = app.yEditField_3.Value;
            image = app.inputImg;
            app.outputImg = applyTranslate(app, image, x, y);
            imshow(app.outputImg, 'Parent', app.OutUIAxes);
        end

        % Button pushed function: RotateindegButton
        function RotateindegButtonPushed(app, event)
            q = app.qEditField.Value;
            image = app.inputImg;
            app.outputImg = applyRotate(app, image, q);
            imshow(app.outputImg, 'Parent', app.OutUIAxes);
        end

        % Button pushed function: ApplyButton_4
        function ApplyButton_4Pushed(app, event)
            image = app.inputImg;
            app.outputImg = FTransform(app, image);
            imagesc(app.outputImg, 'Parent', app.OutUIAxes);
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            image = app.inputImg;
            [cA,cH,cV,cD] = applyDWT2(app, image);
            imshow(cA, 'Parent', app.UIAxes2);
            imshow(cH, 'Parent', app.UIAxes3);
            imshow(cV, 'Parent', app.UIAxes4);
            imshow(cD, 'Parent', app.UIAxes5);
            
            %             f = figure;
            %             subplot(2,2,1)
            imshow(cA, 'Parent', app.OutUIAxes)
            %             colormap gray
            %             title('Approximation')
            %             subplot(2,2,2)
            %             imshow(cH, 'Parent', app.OutUIAxes)
            %             colormap gray
            %             title('Horizontal')
            %             subplot(2,2,3)
            %             imshow(cV, 'Parent', app.OutUIAxes)
            %             colormap gray
            %             title('Vertical')
            %             subplot(2,2,4)
            %             imshow(cD, 'Parent', app.OutUIAxes)
            %             colormap gray
            %             title('Diagonal')
            %             app.outputImg = f;
            app.outputImg = cA;
            
        end

        % Button pushed function: ApplyButton_5
        function ApplyButton_5Pushed(app, event)
            image = app.inputImg;
            app.outputImg = applyDCT2(app, image);
            imshow(app.outputImg, 'Parent', app.OutUIAxes);
            colorbar;
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            w = app.InputMinEditField.Value;
            x = app.InputMaxEditField.Value;
            y = app.InputMaxEditField_2.Value;
            z = app.InputMaxEditField_2.Value;
            image = app.inputImg;
            app.outputImg = applyQuality(app, image, w, x, y, z);
            imshow(app.outputImg, 'Parent', app.OutUIAxes);
        end

        % Button pushed function: OpenFusionToolButton
        function OpenFusionToolButtonPushed(app, event)
            fusionapp();
        end

        % Value changed function: SelectcolorModelDropDown
        function SelectcolorModelDropDownValueChanged(app, event)
            value = app.SelectcolorModelDropDown.Value;
            image = app.inputImg;
            
            switch value
                case 'RGB'
                    output=applyRGB(app, image);
                    imshow(output, 'Parent', app.OutUIAxes);
                    
                case 'CMY'
                    output=applyCMY(app, image);
                    imshow(output, 'Parent', app.OutUIAxes);
                    
                case 'CMYK'
                    output=applyCMYK(app, image);
                    imshow(output, 'Parent', app.OutUIAxes);
                    
                case 'HSI'
                    output=applyHSI(app, image);
                    imshow(output, 'Parent', app.OutUIAxes);
                    
                otherwise
                    disp('no model selected');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TransformTab = uitab(app.TabGroup);
            app.TransformTab.Title = 'Transform';
            app.xEditFieldLabel = uilabel(app.TransformTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.TransformTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.TransformTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.TransformTab, 'numeric');
            app.ScaleButton = uibutton(app.TransformTab, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.xEditField_2Label = uilabel(app.TransformTab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.TransformTab, 'numeric');
            app.yEditField_2Label = uilabel(app.TransformTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.TransformTab, 'numeric');
            app.xEditField_3Label = uilabel(app.TransformTab);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.TransformTab, 'numeric');
            app.yEditField_3Label = uilabel(app.TransformTab);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.TransformTab, 'numeric');
            app.qEditFieldLabel = uilabel(app.TransformTab);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.TransformTab, 'numeric');
            app.ShearButton = uibutton(app.TransformTab, 'push');
            app.ShearButton.ButtonPushedFcn = createCallbackFcn(app, @ShearButtonPushed, true);
            app.ShearButton.Text = 'Shear';
            app.TranslateButton = uibutton(app.TransformTab, 'push');
            app.TranslateButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateButtonPushed, true);
            app.TranslateButton.Text = 'Translate';
            app.RotateindegButton = uibutton(app.TransformTab, 'push');
            app.RotateindegButton.ButtonPushedFcn = createCallbackFcn(app, @RotateindegButtonPushed, true);
            app.RotateindegButton.Text = 'Rotate (in deg)';
            app.ApplyButton_4 = uibutton(app.TransformTab, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_4Pushed, true);
            app.ApplyButton_4.Text = 'Apply';
            app.AffineLabel = uilabel(app.TransformTab);
            app.AffineLabel.Text = 'Affine';
            app.FourierTransformLabel = uilabel(app.TransformTab);
            app.FourierTransformLabel.Text = 'Fourier Transform';
            app.DiscreteCosineTransformLabel = uilabel(app.TransformTab);
            app.DiscreteCosineTransformLabel.Text = 'Discrete Cosine Transform';
            app.ApplyButton_5 = uibutton(app.TransformTab, 'push');
            app.ApplyButton_5.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_5Pushed, true);
            app.ApplyButton_5.Text = 'Apply';
            app.WaveletTab = uitab(app.TabGroup);
            app.WaveletTab.Title = 'Wavelet';
            app.UIAxes2 = uiaxes(app.WaveletTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.WaveletTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.WaveletTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.WaveletTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.ApplyButton_2 = uibutton(app.WaveletTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.ApproximationLabel = uilabel(app.WaveletTab);
            app.ApproximationLabel.Text = 'Approximation';
            app.HorizontalLabel = uilabel(app.WaveletTab);
            app.HorizontalLabel.Text = 'Horizontal';
            app.VerticalLabel = uilabel(app.WaveletTab);
            app.VerticalLabel.Text = 'Vertical';
            app.DiagonalLabel = uilabel(app.WaveletTab);
            app.DiagonalLabel.Text = 'Diagonal';
            app.QualityTab = uitab(app.TabGroup);
            app.QualityTab.Title = 'Quality';
            app.InputMinEditFieldLabel = uilabel(app.QualityTab);
            app.InputMinEditFieldLabel.Text = 'Input Min';
            app.InputMinEditField = uieditfield(app.QualityTab, 'numeric');
            app.InputMaxEditFieldLabel = uilabel(app.QualityTab);
            app.InputMaxEditFieldLabel.Text = 'Input  Max';
            app.InputMaxEditField = uieditfield(app.QualityTab, 'numeric');
            app.ApplyChangesButton = uibutton(app.QualityTab, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.InputMinEditField_2Label = uilabel(app.QualityTab);
            app.InputMinEditField_2Label.Text = 'Input Min';
            app.InputMinEditField_2 = uieditfield(app.QualityTab, 'numeric');
            app.InputMaxEditField_2Label = uilabel(app.QualityTab);
            app.InputMaxEditField_2Label.Text = 'Input Max';
            app.InputMaxEditField_2 = uieditfield(app.QualityTab, 'numeric');
            app.BrightnessLabel = uilabel(app.QualityTab);
            app.BrightnessLabel.Text = 'Brightness';
            app.ContrastLabel_2 = uilabel(app.QualityTab);
            app.ContrastLabel_2.Text = 'Contrast';
            app.AdvancedOptionsTab = uitab(app.TabGroup);
            app.AdvancedOptionsTab.Title = 'Advanced Options';
            app.SelectcolorModelDropDownLabel = uilabel(app.AdvancedOptionsTab);
            app.SelectcolorModelDropDownLabel.Text = 'Select color Model ';
            app.SelectcolorModelDropDown = uidropdown(app.AdvancedOptionsTab);
            app.SelectcolorModelDropDown.Items = {'None', 'RGB', 'CMY', 'CMYK', 'HSI'};
            app.SelectcolorModelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectcolorModelDropDownValueChanged, true);
            app.SelectcolorModelDropDown.Value = 'None';
            app.AdvancedDropDownLabel = uilabel(app.AdvancedOptionsTab);
            app.AdvancedDropDownLabel.Text = 'Advanced ';
            app.AdvancedDropDown = uidropdown(app.AdvancedOptionsTab);
            app.AdvancedDropDown.Items = {'Compress Image', 'Insert Visible Watermark', 'Insert Invisible Watermark', 'Remove S&P Noise', 'Remove Gaussian Noise'};
            app.AdvancedDropDown.Value = 'Compress Image';
            app.ApplyButton = uibutton(app.AdvancedOptionsTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.OpenFilterToolButton = uibutton(app.AdvancedOptionsTab, 'push');
            app.OpenFilterToolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFilterToolButtonPushed, true);
            app.OpenFilterToolButton.Text = 'Open Filter Tool ';
            app.OpenNoiseToolButton = uibutton(app.AdvancedOptionsTab, 'push');
            app.OpenNoiseToolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenNoiseToolButtonPushed, true);
            app.OpenNoiseToolButton.Text = 'Open Noise Tool';
            app.OpenFusionToolButton = uibutton(app.AdvancedOptionsTab, 'push');
            app.OpenFusionToolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFusionToolButtonPushed, true);
            app.OpenFusionToolButton.Text = 'Open Fusion Tool';
            app.InpUIAxes = uiaxes(app.UIFigure);
            title(app.InpUIAxes, 'Title')
            xlabel(app.InpUIAxes, 'X')
            ylabel(app.InpUIAxes, 'Y')
            app.OutUIAxes = uiaxes(app.UIFigure);
            title(app.OutUIAxes, 'Title')
            xlabel(app.OutUIAxes, 'X')
            ylabel(app.OutUIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end