classdef noise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputUIAxes                    matlab.ui.control.UIAxes
        AddNoiseLabel                  matlab.ui.control.Label
        OutputUIAxes                   matlab.ui.control.UIAxes
        UploadButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        SelectnoiseDropDownLabel       matlab.ui.control.Label
        SelectnoiseDropDown            matlab.ui.control.DropDown
        MeanEditFieldLabel             matlab.ui.control.Label
        MeanEditField                  matlab.ui.control.NumericEditField
        VarianceEditFieldLabel         matlab.ui.control.Label
        VarianceEditField              matlab.ui.control.NumericEditField
        NoisedensityEditFieldLabel     matlab.ui.control.Label
        NoisedensityEditField          matlab.ui.control.NumericEditField
        ORLabel                        matlab.ui.control.Label
        SineangleEditFieldLabel        matlab.ui.control.Label
        SineangleEditField             matlab.ui.control.NumericEditField
        CyclesEditFieldLabel           matlab.ui.control.Label
        CyclesEditField                matlab.ui.control.NumericEditField
        applynonrepetitivenoiseButton  matlab.ui.control.Button
        applyrepetitvenoiseButton      matlab.ui.control.Button
    end


    properties (Access = public)
        mainInst % instance of the class calling the app
        inputImg % input image
        defaultPic % default folder for pics
        noisePic % noise image
        outputImg % output image
        filterImg % for filtering image files
    end

    methods (Access = public)
    
        function initValues(app)
            %app.SelectnoiseDropDown.Value = 'gaussion'
            app.filterImg = {'*.jpg';'*.jpeg';'*.png';'*.tif' };
            app.defaultPic = './default_pics/';
            app.MeanEditField.Value = 0.5;
            app.VarianceEditField.Value = 0.05;
            app.NoisedensityEditField.Value = 0.10;
            app.SineangleEditField.Value = 0;
            app.CyclesEditField.Value = 10;
        end
        
        function output = getSineNoise(app, rows, cols, cycles, angle)
            output=repmat(sin(linspace(0,cycles*(2*pi),cols)),[rows 1]);
            if (angle ~= 0)
                output = imrotate(output,angle);
            end
            output = 1-output;
        end
        
        function [output1, output2] = addRepetitiveNoise(app, img, noise)
            ftImage = fftshift(fft2(double(img)));
            ftNoise = fftshift(fft2(double(noise)));
            output1 = ftNoise;
            ftNImage = ftImage+ftNoise;
            
            realImage =  real(ifft2(ifftshift(ftNImage)));
            output2 = (realImage - min(realImage(:)))/(max(realImage(:)) - min(realImage(:)));
            output2 = uint8(255*output2);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inst, img)
            initValues(app);
            
            app.mainInst = inst;
            app.inputImg = img;
            
            title(app.InputUIAxes, []);
            xlabel(app.InputUIAxes, []);
            ylabel(app.InputUIAxes, []);
            app.InputUIAxes.XAxis.TickLabels = {};
            app.InputUIAxes.YAxis.TickLabels = {};
            
            title(app.OutputUIAxes, []);
            xlabel(app.OutputUIAxes, []);
            ylabel(app.OutputUIAxes, []);
            app.OutputUIAxes.XAxis.TickLabels = {};
            app.OutputUIAxes.YAxis.TickLabels = {};          
            
            if isempty(img)
                disp('I am not there');
                f = strcat(app.defaultPic, 'no_img.jpg');
                temp = imread(f);
                imshow(temp, 'Parent', app.InputUIAxes);
            else 
                disp('I am there');
                imshow(img, 'Parent', app.InputUIAxes);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            arg = 'success';
            mainCallback(app.mainInst, arg, app.noisePic);
            delete(app)
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile(app.filterImg, ...
                'Select an image');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
                 app.inputImg = imread([path,file]);
                 imshow(app.inputImg, 'Parent', app.InputUIAxes);
            end
        end

        % Button pushed function: applynonrepetitivenoiseButton
        function applynonrepetitivenoiseButtonPushed(app, event)
             switch app.SelectnoiseDropDown.Value
                 case 'gaussian'
                     app.noisePic = imnoise(app.inputImg,'gaussian', ...
                     app.MeanEditField.Value, app.VarianceEditField.Value);
                 case 'localvar'
                     app.noisePic = imnoise(app.inputImg,'localvar', ...
                     app.VarianceEditField.Value * rand(size(app.inputImg)));
                 case 'poisson'
                     app.noisePic = imnoise(app.inputImg,'poisson');
                 case 'salt & pepper'
                     app.noisePic = imnoise(app.inputImg,'salt & pepper', ...
                         app.NoisedensityEditField.Value);
                 case 'speckle'
                     app.noisePic = imnoise(app.inputImg,'speckle', ...
                         app.VarianceEditField.Value);
                 otherwise
                     disp('no noise selected');
             end
             imshow(app.noisePic, 'Parent', app.OutputUIAxes);        
        end

        % Button pushed function: applyrepetitvenoiseButton
        function applyrepetitvenoiseButtonPushed(app, event)
            imGray = rgb2gray(app.inputImg);
            [rows, cols] = size(imGray);
            s = getSineNoise(app, rows, cols, app.CyclesEditField.Value, ...
                app.SineangleEditField.Value);
            [f, app.noisePic] = addRepetitiveNoise(app, imGray, s);
            imshow(app.noisePic, 'Parent', app.OutputUIAxes);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile(app.filterImg, 'Noise File');
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(path,file),...
                    ' and then clicked Save.'])
                filePath = strcat(path,file);
                imwrite(app.noisePic, filePath);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InputUIAxes = uiaxes(app.UIFigure);
            title(app.InputUIAxes, 'Title')
            xlabel(app.InputUIAxes, 'X')
            ylabel(app.InputUIAxes, 'Y')
            app.InputUIAxes.XLim = [0 1];
            app.InputUIAxes.YLim = [0 1];
            app.InputUIAxes.ZLim = [0 1];
            app.InputUIAxes.CLim = [0 1];
            app.InputUIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.InputUIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.InputUIAxes.ZTick = [0 0.5 1];
            app.AddNoiseLabel = uilabel(app.UIFigure);
            app.AddNoiseLabel.Text = 'Add Noise';
            app.OutputUIAxes = uiaxes(app.UIFigure);
            title(app.OutputUIAxes, 'Title')
            xlabel(app.OutputUIAxes, 'X')
            ylabel(app.OutputUIAxes, 'Y')
            app.OutputUIAxes.XLim = [0 1];
            app.OutputUIAxes.YLim = [0 1];
            app.OutputUIAxes.ZLim = [0 1];
            app.OutputUIAxes.CLim = [0 1];
            app.OutputUIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.OutputUIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.OutputUIAxes.ZTick = [0 0.5 1];
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectnoiseDropDownLabel = uilabel(app.UIFigure);
            app.SelectnoiseDropDownLabel.Text = 'Select noise';
            app.SelectnoiseDropDown = uidropdown(app.UIFigure);
            app.SelectnoiseDropDown.Items = {'gaussian', 'localvar', 'poisson', 'salt & pepper', 'speckle'};
            app.SelectnoiseDropDown.Value = 'gaussian';
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.VarianceEditFieldLabel = uilabel(app.UIFigure);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisedensityEditFieldLabel = uilabel(app.UIFigure);
            app.NoisedensityEditFieldLabel.Text = 'Noise density';
            app.NoisedensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.SineangleEditFieldLabel = uilabel(app.UIFigure);
            app.SineangleEditFieldLabel.Text = 'Sine angle';
            app.SineangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.CyclesEditFieldLabel = uilabel(app.UIFigure);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.CyclesEditField = uieditfield(app.UIFigure, 'numeric');
            app.applynonrepetitivenoiseButton = uibutton(app.UIFigure, 'push');
            app.applynonrepetitivenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @applynonrepetitivenoiseButtonPushed, true);
            app.applynonrepetitivenoiseButton.Text = 'apply non-repetitive noise';
            app.applyrepetitvenoiseButton = uibutton(app.UIFigure, 'push');
            app.applyrepetitvenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @applyrepetitvenoiseButtonPushed, true);
            app.applyrepetitvenoiseButton.Text = 'apply repetitve noise';
        end
    end

    methods (Access = public)

        % Construct app
        function app = noise(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end