classdef transformations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ImageconversionLabel      matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        ColorModelsTab            matlab.ui.container.Tab
        ConvertToDropDownLabel    matlab.ui.control.Label
        ConvertToDropDown         matlab.ui.control.DropDown
        AffineFourierTab          matlab.ui.container.Tab
        xEditFieldLabel           matlab.ui.control.Label
        xEditField                matlab.ui.control.NumericEditField
        yEditFieldLabel           matlab.ui.control.Label
        yEditField                matlab.ui.control.NumericEditField
        ScaleButton               matlab.ui.control.Button
        xEditField_2Label         matlab.ui.control.Label
        xEditField_2              matlab.ui.control.NumericEditField
        yEditField_2Label         matlab.ui.control.Label
        yEditField_2              matlab.ui.control.NumericEditField
        xEditField_3Label         matlab.ui.control.Label
        xEditField_3              matlab.ui.control.NumericEditField
        yEditField_3Label         matlab.ui.control.Label
        yEditField_3              matlab.ui.control.NumericEditField
        qEditFieldLabel           matlab.ui.control.Label
        qEditField                matlab.ui.control.NumericEditField
        ShearButton               matlab.ui.control.Button
        TranslateButton           matlab.ui.control.Button
        RotateButton              matlab.ui.control.Button
        FourierButton             matlab.ui.control.Button
        WaveletTab                matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
        ApplyButton               matlab.ui.control.Button
        DiscreteCosineTab         matlab.ui.container.Tab
        ApplyButton_2             matlab.ui.control.Button
        QualityTab                matlab.ui.container.Tab
        InputMinEditFieldLabel    matlab.ui.control.Label
        InputMinEditField         matlab.ui.control.NumericEditField
        InputMaxEditFieldLabel    matlab.ui.control.Label
        InputMaxEditField         matlab.ui.control.NumericEditField
        ApplyChangesButton        matlab.ui.control.Button
        InputMinEditField_2Label  matlab.ui.control.Label
        InputMinEditField_2       matlab.ui.control.NumericEditField
        InputMaxEditField_2Label  matlab.ui.control.Label
        InputMaxEditField_2       matlab.ui.control.NumericEditField
        BrightnessLabel           matlab.ui.control.Label
        ContrastLabel             matlab.ui.control.Label
        UpoadImageButton          matlab.ui.control.Button
        SaveImageButton           matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function output = applyRGB(app, image)
            
            redLayer = image(:,:,1);
            greenLayer = image(:,:,2);
            blueLayer = image(:,:,3);
            output = cat(3, redLayer,blueLayer,greenLayer);
        end
        
        function output = applyHSI(app, image)
            redLayer = image(:,:,1);
            greenLayer = image(:,:,2);
            blueLayer = image(:,:,3);
            H = getHue(app, redLayer, greenLayer, blueLayer);
            disp(size(H));
            S = getSaturation(app, redLayer, greenLayer, blueLayer);
            disp(size(S));
            I = (redLayer+greenLayer+blueLayer)./3;
            disp(size(I));
            output = cat(3,H,S,I);
        end
        
        function output = applyCMYK(app, image)
            image = im2double(image);
            cform = makecform('srgb2cmyk');
            cmykI = applycform(image,cform);
            disp(size(cmykI));
            cLayer = cmykI(:,:,1);
            mLayer = cmykI(:,:,2);
            yLayer = cmykI(:,:,3);
            output = cat(3,cLayer,mLayer,yLayer);
            
        end
        
        function output = applyCMY(app, image)
            image = im2double(image);
            redLayer = image(:,:,1);
            greenLayer = image(:,:,2);
            blueLayer = image(:,:,3);
            
            C = 1 - redLayer;
            M = 1 - greenLayer;
            Y = 1 - blueLayer;
            
            colorCMY = cat(3,C,M,Y);
            output = colorCMY;
        end
        
        function output = getSaturation(app, r,g,b)
            [row, col] = size(r);
            sComponent = zeros(row,col);
            for i=1:row
                for j=1:col
                    minVal1 = min(r(i,j),g(i,j));
                    minVal2 = min(g(i,j),b(i,j));
                    minVal3 = min(minVal1,minVal2);
                    minVal = min(minVal3, min( r(i,j),b(i,j) ));
                    sComponent(i,j) = 1 - ( (3./(r(i,j)+g(i,j)+b(i,j))).*(minVal) );
                end
            end
            output = sComponent;
        end
        
        function output = getHue(app, ruint8,guint8,buint8)
            r = double(ruint8);
            g = double(guint8);
            b = double(buint8);
            [row, col] = size(r);
            hComponent = zeros(row, col);
            num = ( (r-g) + (r-b) )/2;
            den = ( (r-g).^2 + (r-b).*(g-b) ).^2;
            hComponent = acos(num./den);
            temp =  b>g;
            for i=1:row
                for j=1:col
                    if temp(i,j) == 1
                        hComponent(i,j) = 360 - hComponent(i,j);
                    end
                end
            end
            output = hComponent;
        end
        
        %TRANSFORMS
        
        function output = applyScale(app, image, x, y)
            scale_mat = [x 0 0; 0 y 0; 0 0 1];
            ref = imref2d(size(image));
            %scale image
            tform = affine2d(scale_mat);
            scaled = imwarp(image,tform);
            output = scaled;
        end
        
        function output = applyTranslate(app, image, x, y)
            trans_mat = [1 0 0; 0 1 0; x y 1];
            ref = imref2d(size(image));
            %translate image
            tform = affine2d(trans_mat);
            trans = imwarp(image,tform,'outputview',ref);
            output = trans;
        end
        
        function output = applyShear(app, image, x, y)
            shear_mat = [1 y 0; x 1 0; 0 0 1];
            ref = imref2d(size(image));
            %shear image
            tform = affine2d(shear_mat);
            shear = imwarp(image,tform);
            output = shear;
        end
        
        function output = applyRotate(app, image, q)
            rot_mat = [cos(q) sin(q) 0; -sin(q) cos(q) 0; 0 0 1];
            ref = imref2d(size(image));
            %rotate image
            tform = affine2d(rot_mat);
            rot = imwarp(image,tform);
            output = rot;
        end
        
        function output = FTransform(app, image)
            F = fftshift(fft2(image));
            F2 = log(abs(F));
            output = F2;
        end
        
        function [cA,cH,cV,cD] = applyDWT2(app, image)
            [cA,cH,cV,cD] = dwt2(image,'db45');
        end
        
        function output = applyDCT2(app, image)
            J = dct2(image);
            K = log(abs(J));
            output = K;
        end
        
        function output = applyQuality(app, image, w, x, y, z)
            im_bright = imadjust(image,[0,w],[x,1]);
            im_contrast = imadjust(im_bright,[y,1],[0,z]);
            output = histeq(im_contrast);
            %output = im_contrast;
        end
        
    end

    methods (Access = private)

        % Value changed function: ConvertToDropDown
        function ConvertToDropDownValueChanged(app, event)
            value = app.ConvertToDropDown.Value;
            image = imread('USAflag.jpeg');
            
            switch value
                case 'RGB'
                    output=applyRGB(app, image);
                    imshow(output, 'Parent', app.UIAxes);
                    
                case 'CMY'
                    output=applyCMY(app, image);
                    imshow(output, 'Parent', app.UIAxes);
                    
                case 'CMYK'
                    output=applyCMYK(app, image);
                    imshow(output, 'Parent', app.UIAxes);
                    
                case 'HSI'
                    output=applyHSI(app, image);
                    imshow(output, 'Parent', app.UIAxes);
                    
                otherwise
                    disp('no model selected');
            end
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            image = imread('USAflag.jpeg');
            output=applyScale(app, image, x, y);
            imshow(output, 'Parent', app.UIAxes);
            
        end

        % Button pushed function: ShearButton
        function ShearButtonPushed(app, event)
            x = app.xEditField_2.Value;
            y = app.yEditField_2.Value;
            image = imread('USAflag.jpeg');
            output=applyShear(app, image, x, y);
            imshow(output, 'Parent', app.UIAxes);
        end

        % Button pushed function: TranslateButton
        function TranslateButtonPushed(app, event)
            x = app.xEditField_3.Value;
            y = app.yEditField_3.Value;
            image = imread('USAflag.jpeg');
            output=applyTranslate(app, image, x, y);
            imshow(output, 'Parent', app.UIAxes);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            q = app.qEditField.Value;
            image = imread('USAflag.jpeg');
            output=applyRotate(app, image, q);
            imshow(output, 'Parent', app.UIAxes);
        end

        % Button pushed function: FourierButton
        function FourierButtonPushed(app, event)
            image = imread('USAflag.jpeg');
            output = FTransform(app, image);
            imshow(output, 'Parent', app.UIAxes);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            image = imread('USAflag.jpeg');
            [cA,cH,cV,cD]=applyDWT2(app, image);
            imshow(cA, 'Parent', app.UIAxes2);
            imshow(cH, 'Parent', app.UIAxes3);
            imshow(cV, 'Parent', app.UIAxes4);
            imshow(cD, 'Parent', app.UIAxes5);
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            image = imread('USAflag.jpeg');
            output = applyDCT2(app, image);
            imshow(output, 'Parent', app.UIAxes);
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            w = app.InputMinEditField.Value;
            x = app.InputMaxEditField.Value;
            y = app.InputMaxEditField_2.Value;
            z = app.InputMaxEditField_2.Value;
            image = imread('USAflag.jpeg');
            output=applyQuality(app, image, w, x, y, z);
            imshow(output, 'Parent', app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ImageconversionLabel = uilabel(app.UIFigure);
            app.ImageconversionLabel.Text = 'Image conversion';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ColorModelsTab = uitab(app.TabGroup);
            app.ColorModelsTab.Title = 'Color Models';
            app.ConvertToDropDownLabel = uilabel(app.ColorModelsTab);
            app.ConvertToDropDownLabel.Text = 'Convert To';
            app.ConvertToDropDown = uidropdown(app.ColorModelsTab);
            app.ConvertToDropDown.Items = {'None', 'RGB', 'CMY', 'CMYK', 'HSI'};
            app.ConvertToDropDown.ValueChangedFcn = createCallbackFcn(app, @ConvertToDropDownValueChanged, true);
            app.ConvertToDropDown.Value = 'None';
            app.AffineFourierTab = uitab(app.TabGroup);
            app.AffineFourierTab.Title = 'Affine & Fourier';
            app.xEditFieldLabel = uilabel(app.AffineFourierTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.AffineFourierTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.AffineFourierTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.AffineFourierTab, 'numeric');
            app.ScaleButton = uibutton(app.AffineFourierTab, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.xEditField_2Label = uilabel(app.AffineFourierTab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.AffineFourierTab, 'numeric');
            app.yEditField_2Label = uilabel(app.AffineFourierTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.AffineFourierTab, 'numeric');
            app.xEditField_3Label = uilabel(app.AffineFourierTab);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.AffineFourierTab, 'numeric');
            app.yEditField_3Label = uilabel(app.AffineFourierTab);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.AffineFourierTab, 'numeric');
            app.qEditFieldLabel = uilabel(app.AffineFourierTab);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.AffineFourierTab, 'numeric');
            app.ShearButton = uibutton(app.AffineFourierTab, 'push');
            app.ShearButton.ButtonPushedFcn = createCallbackFcn(app, @ShearButtonPushed, true);
            app.ShearButton.Text = 'Shear';
            app.TranslateButton = uibutton(app.AffineFourierTab, 'push');
            app.TranslateButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateButtonPushed, true);
            app.TranslateButton.Text = 'Translate';
            app.RotateButton = uibutton(app.AffineFourierTab, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.FourierButton = uibutton(app.AffineFourierTab, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.FourierButton.Text = 'Fourier';
            app.WaveletTab = uitab(app.TabGroup);
            app.WaveletTab.Title = 'Wavelet';
            app.UIAxes2 = uiaxes(app.WaveletTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.WaveletTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.WaveletTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.WaveletTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.ApplyButton = uibutton(app.WaveletTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DiscreteCosineTab = uitab(app.TabGroup);
            app.DiscreteCosineTab.Title = 'Discrete Cosine';
            app.ApplyButton_2 = uibutton(app.DiscreteCosineTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.QualityTab = uitab(app.TabGroup);
            app.QualityTab.Title = 'Quality';
            app.InputMinEditFieldLabel = uilabel(app.QualityTab);
            app.InputMinEditFieldLabel.Text = 'Input Min';
            app.InputMinEditField = uieditfield(app.QualityTab, 'numeric');
            app.InputMaxEditFieldLabel = uilabel(app.QualityTab);
            app.InputMaxEditFieldLabel.Text = 'Input  Max';
            app.InputMaxEditField = uieditfield(app.QualityTab, 'numeric');
            app.ApplyChangesButton = uibutton(app.QualityTab, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.InputMinEditField_2Label = uilabel(app.QualityTab);
            app.InputMinEditField_2Label.Text = 'Input Min';
            app.InputMinEditField_2 = uieditfield(app.QualityTab, 'numeric');
            app.InputMaxEditField_2Label = uilabel(app.QualityTab);
            app.InputMaxEditField_2Label.Text = 'Input Max';
            app.InputMaxEditField_2 = uieditfield(app.QualityTab, 'numeric');
            app.BrightnessLabel = uilabel(app.QualityTab);
            app.BrightnessLabel.Text = 'Brightness';
            app.ContrastLabel = uilabel(app.QualityTab);
            app.ContrastLabel.Text = 'Contrast';
            app.UpoadImageButton = uibutton(app.UIFigure, 'push');
            app.UpoadImageButton.Text = 'Upoad Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.Text = 'Save Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = transformations

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end